classdef FinalApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        HomeTab                         matlab.ui.container.Tab
        CircuitDiagramLabel             matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        DescriptionTextArea             matlab.ui.control.TextArea
        OPAmpasCircuitAnalyzerLabel     matlab.ui.control.Label
        OPAmpasDropDownLabel            matlab.ui.control.Label
        OPAmpasDropDown                 matlab.ui.control.DropDown
        InvertingTab                    matlab.ui.container.Tab
        OutputVoltageEditField          matlab.ui.control.EditField
        GainAvEditField_8Label_2        matlab.ui.control.Label
        GainAvEditField                 matlab.ui.control.EditField
        GainAvEditField_8Label          matlab.ui.control.Label
        VinVoltsEditField               matlab.ui.control.EditField
        VinVoltsEditFieldLabel          matlab.ui.control.Label
        R1OhmsEditField                 matlab.ui.control.EditField
        R1OhmsLabel                     matlab.ui.control.Label
        RfOhmsEditField                 matlab.ui.control.EditField
        RfOhmsEditFieldLabel            matlab.ui.control.Label
        DescriptionTextArea_2           matlab.ui.control.TextArea
        ClaculateGainButton_2           matlab.ui.control.Button
        Image2                          matlab.ui.control.Image
        OutputparametersLabel           matlab.ui.control.Label
        SimulateinvertingamplifierButton  matlab.ui.control.Button
        InputparametersLabel            matlab.ui.control.Label
        InvertingAmplifierLabel         matlab.ui.control.Label
        NonInvertingTab                 matlab.ui.container.Tab
        OutputVoltageEditField_7        matlab.ui.control.EditField
        OutputVoltageEditField_7Label   matlab.ui.control.Label
        GainAvEditField_13              matlab.ui.control.EditField
        GainAvEditField_13Label         matlab.ui.control.Label
        VinVoltsEditField_2             matlab.ui.control.EditField
        VinVoltsEditField_2Label        matlab.ui.control.Label
        R1OhmsEditField_2               matlab.ui.control.EditField
        R1OhmsEditField_2Label          matlab.ui.control.Label
        RfOhmsEditField_2               matlab.ui.control.EditField
        RfOhmsEditField_2Label          matlab.ui.control.Label
        DescriptionTextArea_8           matlab.ui.control.TextArea
        ClaculateGainButton_9           matlab.ui.control.Button
        Image2_8                        matlab.ui.control.Image
        OutputparametersLabel_7         matlab.ui.control.Label
        SimulateNonInvertingAmplifierButton  matlab.ui.control.Button
        InputparametersLabel_7          matlab.ui.control.Label
        NonInvertingAmplifierLabel      matlab.ui.control.Label
        SummingTab                      matlab.ui.container.Tab
        OutputVoltageEditField_3        matlab.ui.control.EditField
        OutputVoltageEditField_3Label   matlab.ui.control.Label
        GainAvEditField_9               matlab.ui.control.EditField
        GainAvEditField_9Label          matlab.ui.control.Label
        Vin2voltsEditField              matlab.ui.control.EditField
        Vin2voltsEditFieldLabel         matlab.ui.control.Label
        Vin3VoltsEditField              matlab.ui.control.EditField
        Vin3VoltsEditFieldLabel         matlab.ui.control.Label
        Vin1voltsEditField              matlab.ui.control.EditField
        Vin1voltsLabel                  matlab.ui.control.Label
        R3ohmsEditField                 matlab.ui.control.EditField
        R3ohmsLabel                     matlab.ui.control.Label
        R2ohmsEditField                 matlab.ui.control.EditField
        R2ohmsEditFieldLabel            matlab.ui.control.Label
        R1ohmsEditField                 matlab.ui.control.EditField
        R1ohmsEditFieldLabel            matlab.ui.control.Label
        RfohmsEditField                 matlab.ui.control.EditField
        RfohmsEditFieldLabel            matlab.ui.control.Label
        DescriptionTextArea_4           matlab.ui.control.TextArea
        ClaculateGainButton_4           matlab.ui.control.Button
        Image2_6                        matlab.ui.control.Image
        OutputparametersLabel_3         matlab.ui.control.Label
        SimulateSummingAmplifierButton  matlab.ui.control.Button
        InputparametersLabel_3          matlab.ui.control.Label
        SummingAmplifierLabel           matlab.ui.control.Label
        DifferentialTab                 matlab.ui.container.Tab
        OutputVoltageEditField_4        matlab.ui.control.EditField
        OutputVoltageEditField_4Label   matlab.ui.control.Label
        GainAvEditField_10              matlab.ui.control.EditField
        GainAvEditField_10Label         matlab.ui.control.Label
        Vin2voltsEditField_2            matlab.ui.control.EditField
        Vin2voltsEditField_2Label       matlab.ui.control.Label
        Vin1voltsEditField_2            matlab.ui.control.EditField
        Vin1voltsEditField_2Label       matlab.ui.control.Label
        R2ohmsEditField_2               matlab.ui.control.EditField
        R2ohmsEditField_2Label          matlab.ui.control.Label
        R1ohmsEditField_2               matlab.ui.control.EditField
        R1ohmsEditField_2Label          matlab.ui.control.Label
        RfohmsEditField_2               matlab.ui.control.EditField
        RfohmsEditField_2Label          matlab.ui.control.Label
        DescriptionTextArea_5           matlab.ui.control.TextArea
        ClaculateGainButton             matlab.ui.control.Button
        Image2_7                        matlab.ui.control.Image
        OutputparametersLabel_4         matlab.ui.control.Label
        SimulateDifferentialButton      matlab.ui.control.Button
        InputparametersLabel_4          matlab.ui.control.Label
        DifferentialAmplifierLabel      matlab.ui.control.Label
        DifferentiatorTab               matlab.ui.container.Tab
        OutputVoltageEditField_5        matlab.ui.control.EditField
        OutputVoltageEditField_5Label   matlab.ui.control.Label
        GainAvEditField_11              matlab.ui.control.EditField
        GainAvEditField_11Label         matlab.ui.control.Label
        VinvoltsEditField               matlab.ui.control.EditField
        VinvoltsEditFieldLabel          matlab.ui.control.Label
        CffaradsEditField               matlab.ui.control.EditField
        CffaradsEditFieldLabel          matlab.ui.control.Label
        RfohmsEditField_3               matlab.ui.control.EditField
        RfohmsEditField_3Label          matlab.ui.control.Label
        ClaculateGainButton_7           matlab.ui.control.Button
        DescriptionTextArea_6           matlab.ui.control.TextArea
        Image2_4                        matlab.ui.control.Image
        OutputparametersLabel_5         matlab.ui.control.Label
        SimulateDiffetrentiatorButton   matlab.ui.control.Button
        InputparametersLabel_5          matlab.ui.control.Label
        DifferentiatorLabel             matlab.ui.control.Label
        IntegratorTab                   matlab.ui.container.Tab
        VinvoltsEditField_2             matlab.ui.control.EditField
        VinvoltsEditField_2Label        matlab.ui.control.Label
        OutputVoltageEditField_6        matlab.ui.control.EditField
        OutputVoltageEditField_6Label   matlab.ui.control.Label
        GainAvEditField_12              matlab.ui.control.EditField
        GainAvEditField_12Label         matlab.ui.control.Label
        ClaculateGainButton_8           matlab.ui.control.Button
        CfaradsEditField                matlab.ui.control.EditField
        CfaradsEditFieldLabel           matlab.ui.control.Label
        RinohmsEditField                matlab.ui.control.EditField
        RinohmsEditFieldLabel           matlab.ui.control.Label
        DescriptionTextArea_7           matlab.ui.control.TextArea
        Image2_5                        matlab.ui.control.Image
        OutputparametersLabel_6         matlab.ui.control.Label
        SimulateIntegratorButton        matlab.ui.control.Button
        InputparametersLabel_6          matlab.ui.control.Label
        IntegratorLabel                 matlab.ui.control.Label
    end


    % Public properties that correspond to the Simulink model
    properties (Access = public, Transient)
        Simulation simulink.Simulation
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateinvertingamplifierButton
        function SimulateinvertingamplifierButtonPushed(app, event)
             modelPath = 'inverting.slx';
             load_system(modelPath);
             open_system(modelPath);
             simOut = sim(modelPath);
        end

        % Clicked callback: OPAmpasDropDown
        function OPAmpasDropDownClicked(app, event)
          
            
        end

        % Value changed function: OPAmpasDropDown
        function OPAmpasDropDownValueChanged(app, event)
        
value = app.OPAmpasDropDown.Value;
switch value
    case 'Inverting amplifier'
        app.TabGroup.SelectedTab = app.InvertingTab;


    case 'Non-inverting amplifier'
        app.TabGroup.SelectedTab = app.NonInvertingTab;
     

    case 'Summing amplifier'
        app.TabGroup.SelectedTab = app.SummingTab;


    case 'Differentiator'
        app.TabGroup.SelectedTab = app.DifferentiatorTab;
        

    case 'Integrator'
        app.TabGroup.SelectedTab = app.IntegratorTab;


    case 'Differential Amplifier'
        app.TabGroup.SelectedTab = app.DifferentialTab;


end

        end

        % Callback function
        function GainAvEditFieldValueChanged(app, event)
            value = app.GainAvEditField.Value;
            value=Av;
        end

        % Callback function
        function SimulateNonInvertingAmplifierButtonPushed(app, event)
             
        end

        % Button pushed function: SimulateSummingAmplifierButton
        function SimulateSummingAmplifierButtonPushed(app, event)
             modelPath = 'summerm.slx';
             load_system(modelPath);
             open_system(modelPath);
             simOut = sim(modelPath);
        end

        % Button pushed function: SimulateDiffetrentiatorButton
        function SimulateDiffetrentiatorButtonPushed(app, event)
             modelPath = 'Differentiator.slx';
             load_system(modelPath);
             open_system(modelPath);
             simOut = sim(modelPath);
        end

        % Button pushed function: SimulateIntegratorButton
        function SimulateIntegratorButtonPushed(app, event)
             modelPath = 'Integrator.slx';
             load_system(modelPath);
             open_system(modelPath);
             simOut = sim(modelPath);
        end

        % Button pushed function: SimulateDifferentialButton
        function SimulateDifferentialButtonPushed(app, event)
             modelPath = 'differential.slx';
             load_system(modelPath);
             open_system(modelPath);
             simOut = sim(modelPath);
        end

        % Button pushed function: ClaculateGainButton_2
        function ClaculateGainButton_2Pushed(app, event)
            
        Rf = app.RfOhmsEditField.Value;
        R1 = app.R1OhmsEditField.Value;
        Vin = app.VinVoltsEditField.Value;

        
        Av = -(Rf / R1);
        Vout = Av * Vin;

        app.GainAvEditField.Value = num2str(Av);
        app.OutputVoltageEditField.Value = num2str(Vout);
           

        end

        % Callback function
        function ClaculateGainButton_3Pushed(app, event)
        
        end

        % Button pushed function: ClaculateGainButton_4
        function ClaculateGainButton_4Pushed(app, event)
       Rf = app.RfohmsEditField.Value;
R1 = app.R1ohmsEditField.Value;
R2 = app.R2ohmsEditField.Value;
R3 = app.R3ohmsEditField.Value;

Vin1 = app.Vin1voltsEditField.Value;
Vin2 = app.Vin2voltsEditField.Value;
Vin3 = app.Vin3VoltsEditField.Value;  % Fixed here

Av = -(Rf/R1 + Rf/R2 + Rf/R3);
Vout = -Rf .* (Vin1./R1 + Vin2./R2 + Vin3./R3);  % Element-wise ops

app.GainAvEditField_9.Value = num2str(Av);
app.OutputVoltageEditField_3.Value = num2str(Vout);


        end

        % Button pushed function: ClaculateGainButton
        function ClaculateGainButtonPushed(app, event)
             
       Rf = app.RfohmsEditField_2.Value;
       R1=app.R1ohmsEditField_2.Value;
       R2=app.R2ohmsEditField_2.Value;
       Vin1=app.Vin1voltsEditField_2.Value;
       Vin2=app.Vin2voltsEditField.Value;

        Av = -(Rf/R1);
        Vout = Av*(Vin2 - Vin1);

        app.GainAvEditField_10.Value = num2str(Av);
        app.OutputVoltageEditField_4.Value = num2str(Vout);
        end

        % Callback function
        function ClaculateGainButton_5Pushed(app, event)
            app.GainAvEditField_5.Value=Av;
        end

        % Button pushed function: ClaculateGainButton_7
        function ClaculateGainButton_7Pushed(app, event)
        Rf=app.RfohmsEditField_3.Value;
        C=app.CffaradsEditField.Value;
        f=1000;
        Vin = app.VinvoltsEditField.Value;
       
        Av = -(Rf.*C.*f);
        Vout = (Av.*Vin);

        app.GainAvEditField_11.Value = num2str(Av);
        app.OutputVoltageEditField_5.Value = num2str(Vout);

        end

        % Button pushed function: ClaculateGainButton_8
        function ClaculateGainButton_8Pushed(app, event)
         Rf = app.RinohmsEditField.Value;
        C = app.CfaradsEditField.Value;
        f = 1000;
        Vin = app.VinvoltsEditField_2.Value;

       
        Av = -1 ./ (Rf .* C .* f);
        Vout = Av .* Vin;

        app.GainAvEditField_12.Value = num2str(Av);
        app.OutputVoltageEditField_6.Value = num2str(Vout);
        end

        % Callback function
        function PlotForInvertingAmpButtonPushed(app, event)
            Rf = app.RfOhmsEditField_2.Value;
    R1 = app.R1OhmsEditField_2.Value;
    Vin = app.VinVoltsEditField_2.Value;

    % Create simple gain transfer function
    Av = -Rf / R1;
    sys = tf(Av, 1);  % tf(numerator, denominator), constant system

    bode(app.UIAxes, sys);
    grid(app.UIAxes, 'on');
        end

        % Button pushed function: SimulateNonInvertingAmplifierButton
        function SimulateNonInvertingAmplifierButtonPushed2(app, event)
            modelPath = 'NonInv.slx';
             load_system(modelPath);
             open_system(modelPath);
             simOut = sim(modelPath);
        end

        % Button pushed function: ClaculateGainButton_9
        function ClaculateGainButton_9Pushed(app, event)
            Rf = app.RfOhmsEditField_2.Value;
        R1 = app.R1OhmsEditField_2.Value;
        Vin = app.VinVoltsEditField_2.Value;

      
        Av = 1 + Rf / R1;
         Vout = Av * Vin;

        app.GainAvEditField_13.Value = num2str(Av);
        app.OutputVoltageEditField_7.Value = num2str(Vout);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.OPAmpasDropDown = uidropdown(app.HomeTab);
            app.OPAmpasDropDown.Items = {'Inverting amplifier', 'Differential Amplifier', 'Non-inverting amplifier', 'Summing amplifier', 'Differentiator', 'Integrator'};
            app.OPAmpasDropDown.ValueChangedFcn = createCallbackFcn(app, @OPAmpasDropDownValueChanged, true);
            app.OPAmpasDropDown.ClickedFcn = createCallbackFcn(app, @OPAmpasDropDownClicked, true);
            app.OPAmpasDropDown.Value = 'Integrator';
            app.OPAmpasDropDownLabel = uilabel(app.HomeTab);
            app.OPAmpasDropDownLabel.Text = 'OP-Amp as';
            app.OPAmpasCircuitAnalyzerLabel = uilabel(app.HomeTab);
            app.OPAmpasCircuitAnalyzerLabel.Text = 'OP Amp as Circuit Analyzer';
            app.DescriptionTextArea = uitextarea(app.HomeTab);
            app.DescriptionTextArea.Editable = 'off';
            app.DescriptionTextArea.Value = {'An operational amplifier (op-amp) is a high-gain electronic voltage amplifier with differential inputs and a single-ended output. It is widely used in signal processing, control systems, and instrumentation. Op-amps can perform mathematical operations like addition, subtraction, integration, and differentiation. They are commonly configured as amplifiers, filters, oscillators, and comparators. The ideal op-amp has infinite gain, infinite input impedance, and zero output impedance. Practical op-amps, such as the popular 741 IC, exhibit high gain and stability. Their versatility and ease of use make them essential in analog and mixed-signal circuits across various engineering applications.'};
            app.Image = uiimage(app.HomeTab);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2025-03-16 213712.png');
            app.CircuitDiagramLabel = uilabel(app.HomeTab);
            app.CircuitDiagramLabel.Text = 'Circuit Diagram';
            app.InvertingTab = uitab(app.TabGroup);
            app.InvertingTab.Title = 'Inverting';
            app.InvertingAmplifierLabel = uilabel(app.InvertingTab);
            app.InvertingAmplifierLabel.Text = 'Inverting Amplifier';
            app.InputparametersLabel = uilabel(app.InvertingTab);
            app.InputparametersLabel.Text = 'Input parameters';
            app.SimulateinvertingamplifierButton = uibutton(app.InvertingTab, 'push');
            app.SimulateinvertingamplifierButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateinvertingamplifierButtonPushed, true);
            app.SimulateinvertingamplifierButton.Text = 'Simulate inverting amplifier';
            app.OutputparametersLabel = uilabel(app.InvertingTab);
            app.OutputparametersLabel.Text = 'Output parameters';
            app.Image2 = uiimage(app.InvertingTab);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'Inverting.png');
            app.ClaculateGainButton_2 = uibutton(app.InvertingTab, 'push');
            app.ClaculateGainButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClaculateGainButton_2Pushed, true);
            app.ClaculateGainButton_2.Text = 'Claculate Gain';
            app.DescriptionTextArea_2 = uitextarea(app.InvertingTab);
            app.DescriptionTextArea_2.Editable = 'off';
            app.DescriptionTextArea_2.Value = {'1. Inverting Amplifier  '; '   Configuration: '; '  - The input signal is applied to the inverting terminal (-), while the non-inverting terminal (+) is grounded.  '; '  - A feedback resistor R_f is connected from the output to the inverting terminal, and an input resistor             (R_in) is connected between the input and the inverting terminal.  '; '   Gain:  '; '  - The voltage gain is given by:  '; '     A_v = -{R_f/}{R_in}  - '; '     The negative sign indicates a 180-degree phase shift between the input and output.  '; '   Input Impedance: '; '  - Relatively low, determined by R_{in} .  '; '- Output Impedance:  '; '  - Very low due to the negative feedback, allowing effective load driving.  '; '- Phase: '; '  - Output is inverted (opposite in polarity to input).  '; '- Application: '; '  - Used in audio processing, active filters, and sensor interfacing where phase inversion is acceptable.  '; ''};
            app.RfOhmsEditFieldLabel = uilabel(app.InvertingTab);
            app.RfOhmsEditFieldLabel.Text = 'Rf(Ohms)';
            app.RfOhmsEditField = uieditfield(app.InvertingTab, 'text');
            app.R1OhmsLabel = uilabel(app.InvertingTab);
            app.R1OhmsLabel.Text = 'R1(Ohms)';
            app.R1OhmsEditField = uieditfield(app.InvertingTab, 'text');
            app.VinVoltsEditFieldLabel = uilabel(app.InvertingTab);
            app.VinVoltsEditFieldLabel.Text = 'Vin(Volts)';
            app.VinVoltsEditField = uieditfield(app.InvertingTab, 'text');
            app.GainAvEditField_8Label = uilabel(app.InvertingTab);
            app.GainAvEditField_8Label.Text = 'Gain (Av)';
            app.GainAvEditField = uieditfield(app.InvertingTab, 'text');
            app.GainAvEditField_8Label_2 = uilabel(app.InvertingTab);
            app.GainAvEditField_8Label_2.Text = 'Output Voltage';
            app.OutputVoltageEditField = uieditfield(app.InvertingTab, 'text');
            app.NonInvertingTab = uitab(app.TabGroup);
            app.NonInvertingTab.Title = 'NonInverting';
            app.NonInvertingAmplifierLabel = uilabel(app.NonInvertingTab);
            app.NonInvertingAmplifierLabel.Text = 'Non Inverting Amplifier';
            app.InputparametersLabel_7 = uilabel(app.NonInvertingTab);
            app.InputparametersLabel_7.Text = 'Input parameters';
            app.SimulateNonInvertingAmplifierButton = uibutton(app.NonInvertingTab, 'push');
            app.SimulateNonInvertingAmplifierButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateNonInvertingAmplifierButtonPushed2, true);
            app.SimulateNonInvertingAmplifierButton.Text = 'Simulate Non Inverting Amplifier';
            app.OutputparametersLabel_7 = uilabel(app.NonInvertingTab);
            app.OutputparametersLabel_7.Text = 'Output parameters';
            app.Image2_8 = uiimage(app.NonInvertingTab);
            app.Image2_8.ImageSource = fullfile(pathToMLAPP, 'NonInverting.png');
            app.ClaculateGainButton_9 = uibutton(app.NonInvertingTab, 'push');
            app.ClaculateGainButton_9.ButtonPushedFcn = createCallbackFcn(app, @ClaculateGainButton_9Pushed, true);
            app.ClaculateGainButton_9.Text = 'Claculate Gain';
            app.DescriptionTextArea_8 = uitextarea(app.NonInvertingTab);
            app.DescriptionTextArea_8.Editable = 'off';
            app.DescriptionTextArea_8.Value = {'2. Non-Inverting Amplifier '; '   Configuration:'; '  - The input signal is applied to the non-inverting terminal (+), while the inverting terminal (-) is connected to the output through a feedback resistor  Rf.  '; '  - Another resistor Rin is connected from the inverting terminal to the ground.  '; '-  Gain:  '; '  - The voltage gain is:  '; '     Av = 1 + Rf/Rin  '; '  - The output is in phase with the input.  '; '-  Input Impedance:  '; '  - Very high, typically in the order of megaohms.  '; '-  Output Impedance:  '; '  - Low, providing excellent load driving capabilities.  '; '-  Phase: '; '  - Output remains in phase with the input signal.  '; '-  Application:  '; '  - Used in buffer circuits, voltage followers, and sensor amplifiers where input impedance needs to be maintained high.'; ''};
            app.RfOhmsEditField_2Label = uilabel(app.NonInvertingTab);
            app.RfOhmsEditField_2Label.Text = 'Rf(Ohms)';
            app.RfOhmsEditField_2 = uieditfield(app.NonInvertingTab, 'text');
            app.R1OhmsEditField_2Label = uilabel(app.NonInvertingTab);
            app.R1OhmsEditField_2Label.Text = 'R1(Ohms)';
            app.R1OhmsEditField_2 = uieditfield(app.NonInvertingTab, 'text');
            app.VinVoltsEditField_2Label = uilabel(app.NonInvertingTab);
            app.VinVoltsEditField_2Label.Text = 'Vin(Volts)';
            app.VinVoltsEditField_2 = uieditfield(app.NonInvertingTab, 'text');
            app.GainAvEditField_13Label = uilabel(app.NonInvertingTab);
            app.GainAvEditField_13Label.Text = 'Gain (Av)';
            app.GainAvEditField_13 = uieditfield(app.NonInvertingTab, 'text');
            app.OutputVoltageEditField_7Label = uilabel(app.NonInvertingTab);
            app.OutputVoltageEditField_7Label.Text = 'Output Voltage';
            app.OutputVoltageEditField_7 = uieditfield(app.NonInvertingTab, 'text');
            app.SummingTab = uitab(app.TabGroup);
            app.SummingTab.Title = 'Summing';
            app.SummingAmplifierLabel = uilabel(app.SummingTab);
            app.SummingAmplifierLabel.Text = 'Summing Amplifier';
            app.InputparametersLabel_3 = uilabel(app.SummingTab);
            app.InputparametersLabel_3.Text = 'Input parameters';
            app.SimulateSummingAmplifierButton = uibutton(app.SummingTab, 'push');
            app.SimulateSummingAmplifierButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateSummingAmplifierButtonPushed, true);
            app.SimulateSummingAmplifierButton.Text = 'Simulate Summing Amplifier';
            app.OutputparametersLabel_3 = uilabel(app.SummingTab);
            app.OutputparametersLabel_3.Text = 'Output parameters';
            app.Image2_6 = uiimage(app.SummingTab);
            app.Image2_6.ImageSource = fullfile(pathToMLAPP, 'Summer.png');
            app.ClaculateGainButton_4 = uibutton(app.SummingTab, 'push');
            app.ClaculateGainButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClaculateGainButton_4Pushed, true);
            app.ClaculateGainButton_4.Text = 'Claculate Gain';
            app.DescriptionTextArea_4 = uitextarea(app.SummingTab);
            app.DescriptionTextArea_4.Editable = 'off';
            app.DescriptionTextArea_4.Value = {'3. Summing Amplifier'; '- Configuration:  '; '  - Multiple input signals are applied to the inverting terminal through individual resistors ( R_1, R_2, R_3, ...,R_n).  '; '  - A feedback resistor ( R_f ) is connected from the output to the inverting terminal.  '; '  - The non-inverting terminal is grounded.  '; '- Output Voltage: '; '  - The output is the weighted sum of the input signals:  '; '     V_{out} = -({R_f}/{R_1} V_1 + {R_f}/{R_2} V_2 +... {R_f}/{R_n} V_n )'; '- Phase:  '; '  - Output is inverted.  '; '- Input Impedance: '; '  - Determined by the input resistors.  '; '- Application:  '; '  - Commonly used in audio mixers, digital-to-analog converters (DACs), and weighted voltage addition in control systems.'; ''};
            app.RfohmsEditFieldLabel = uilabel(app.SummingTab);
            app.RfohmsEditFieldLabel.Text = 'Rf(ohms)';
            app.RfohmsEditField = uieditfield(app.SummingTab, 'text');
            app.R1ohmsEditFieldLabel = uilabel(app.SummingTab);
            app.R1ohmsEditFieldLabel.Text = 'R1(ohms)';
            app.R1ohmsEditField = uieditfield(app.SummingTab, 'text');
            app.R2ohmsEditFieldLabel = uilabel(app.SummingTab);
            app.R2ohmsEditFieldLabel.Text = 'R2(ohms)';
            app.R2ohmsEditField = uieditfield(app.SummingTab, 'text');
            app.R3ohmsLabel = uilabel(app.SummingTab);
            app.R3ohmsLabel.Text = 'R3(ohms)';
            app.R3ohmsEditField = uieditfield(app.SummingTab, 'text');
            app.Vin1voltsLabel = uilabel(app.SummingTab);
            app.Vin1voltsLabel.Text = 'Vin1(volts)';
            app.Vin1voltsEditField = uieditfield(app.SummingTab, 'text');
            app.Vin3VoltsEditFieldLabel = uilabel(app.SummingTab);
            app.Vin3VoltsEditFieldLabel.Text = 'Vin3(Volts)';
            app.Vin3VoltsEditField = uieditfield(app.SummingTab, 'text');
            app.Vin2voltsEditFieldLabel = uilabel(app.SummingTab);
            app.Vin2voltsEditFieldLabel.Text = 'Vin2(volts)';
            app.Vin2voltsEditField = uieditfield(app.SummingTab, 'text');
            app.GainAvEditField_9Label = uilabel(app.SummingTab);
            app.GainAvEditField_9Label.Text = 'Gain (Av)';
            app.GainAvEditField_9 = uieditfield(app.SummingTab, 'text');
            app.OutputVoltageEditField_3Label = uilabel(app.SummingTab);
            app.OutputVoltageEditField_3Label.Text = 'Output Voltage';
            app.OutputVoltageEditField_3 = uieditfield(app.SummingTab, 'text');
            app.DifferentialTab = uitab(app.TabGroup);
            app.DifferentialTab.Title = 'Differential';
            app.DifferentialAmplifierLabel = uilabel(app.DifferentialTab);
            app.DifferentialAmplifierLabel.Text = 'Differential Amplifier';
            app.InputparametersLabel_4 = uilabel(app.DifferentialTab);
            app.InputparametersLabel_4.Text = 'Input parameters';
            app.SimulateDifferentialButton = uibutton(app.DifferentialTab, 'push');
            app.SimulateDifferentialButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateDifferentialButtonPushed, true);
            app.SimulateDifferentialButton.Text = 'Simulate Differential';
            app.OutputparametersLabel_4 = uilabel(app.DifferentialTab);
            app.OutputparametersLabel_4.Text = 'Output parameters';
            app.Image2_7 = uiimage(app.DifferentialTab);
            app.Image2_7.ImageSource = fullfile(pathToMLAPP, 'Screenshot 2025-03-30 025241.png');
            app.ClaculateGainButton = uibutton(app.DifferentialTab, 'push');
            app.ClaculateGainButton.ButtonPushedFcn = createCallbackFcn(app, @ClaculateGainButtonPushed, true);
            app.ClaculateGainButton.Text = 'Claculate Gain';
            app.DescriptionTextArea_5 = uitextarea(app.DifferentialTab);
            app.DescriptionTextArea_5.Editable = 'off';
            app.DescriptionTextArea_5.Value = {'4. Differential Amplifier  '; '- Configuration: '; '  - Two input voltages ( V_1 and  V_2 ) are applied to both inverting and non-inverting terminals.  '; '  - Feedback and input resistors (R_f and R_1) are used to control the gain.  '; '- Output Voltage: '; '  - The amplifier subtracts the input signals and amplifies the difference:  '; '   V_{out} = ({R_f}/{R_1})*(V_2 - V_1) '; '- Phase:  '; '  - Output depends on the relative polarity of the inputs.  '; '- Common-Mode Rejection:  '; '  - It rejects common-mode signals (noise or interference common to both inputs) effectively, characterized by a high Common-Mode Rejection Ratio (CMRR).  '; '- Application:**  '; '  - Widely used in instrumentation amplifiers for precise sensor measurements and noise rejection applications.  '; ''; ''};
            app.RfohmsEditField_2Label = uilabel(app.DifferentialTab);
            app.RfohmsEditField_2Label.Text = 'Rf(ohms)';
            app.RfohmsEditField_2 = uieditfield(app.DifferentialTab, 'text');
            app.R1ohmsEditField_2Label = uilabel(app.DifferentialTab);
            app.R1ohmsEditField_2Label.Text = 'R1(ohms)';
            app.R1ohmsEditField_2 = uieditfield(app.DifferentialTab, 'text');
            app.R2ohmsEditField_2Label = uilabel(app.DifferentialTab);
            app.R2ohmsEditField_2Label.Text = 'R2(ohms)';
            app.R2ohmsEditField_2 = uieditfield(app.DifferentialTab, 'text');
            app.Vin1voltsEditField_2Label = uilabel(app.DifferentialTab);
            app.Vin1voltsEditField_2Label.Text = 'Vin1(volts)';
            app.Vin1voltsEditField_2 = uieditfield(app.DifferentialTab, 'text');
            app.Vin2voltsEditField_2Label = uilabel(app.DifferentialTab);
            app.Vin2voltsEditField_2Label.Text = 'Vin2(volts)';
            app.Vin2voltsEditField_2 = uieditfield(app.DifferentialTab, 'text');
            app.GainAvEditField_10Label = uilabel(app.DifferentialTab);
            app.GainAvEditField_10Label.Text = 'Gain (Av)';
            app.GainAvEditField_10 = uieditfield(app.DifferentialTab, 'text');
            app.OutputVoltageEditField_4Label = uilabel(app.DifferentialTab);
            app.OutputVoltageEditField_4Label.Text = 'Output Voltage';
            app.OutputVoltageEditField_4 = uieditfield(app.DifferentialTab, 'text');
            app.DifferentiatorTab = uitab(app.TabGroup);
            app.DifferentiatorTab.Title = 'Differentiator';
            app.DifferentiatorLabel = uilabel(app.DifferentiatorTab);
            app.DifferentiatorLabel.Text = 'Differentiator';
            app.InputparametersLabel_5 = uilabel(app.DifferentiatorTab);
            app.InputparametersLabel_5.Text = 'Input parameters';
            app.SimulateDiffetrentiatorButton = uibutton(app.DifferentiatorTab, 'push');
            app.SimulateDiffetrentiatorButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateDiffetrentiatorButtonPushed, true);
            app.SimulateDiffetrentiatorButton.Text = 'Simulate Diffetrentiator';
            app.OutputparametersLabel_5 = uilabel(app.DifferentiatorTab);
            app.OutputparametersLabel_5.Text = 'Output parameters';
            app.Image2_4 = uiimage(app.DifferentiatorTab);
            app.Image2_4.ImageSource = fullfile(pathToMLAPP, 'differentiator.png');
            app.DescriptionTextArea_6 = uitextarea(app.DifferentiatorTab);
            app.DescriptionTextArea_6.Editable = 'off';
            app.DescriptionTextArea_6.Value = {'5. Differentiator'; '- Configuration:  '; '  - The input is applied through a capacitor  C , while a resistor  R  is used in the feedback path.  '; '- Output Voltage:'; '  - The output is proportional to the rate of change (derivative) of the input:  '; '    V_out = -RC*dV_{in}/{dt}  '; '- Phase:  '; '  - The output leads the input by 90 degrees, making it useful for detecting sudden changes in input.  '; '- Frequency Response:  '; '  - High-frequency components are amplified, which can introduce noise. Sometimes a small capacitor is added in parallel with the feedback resistor to reduce high-frequency noise.  '; '- Application: '; '  - Used in edge detection, motion detection, waveform generation, and frequency modulation (FM) demodulators.'};
            app.ClaculateGainButton_7 = uibutton(app.DifferentiatorTab, 'push');
            app.ClaculateGainButton_7.ButtonPushedFcn = createCallbackFcn(app, @ClaculateGainButton_7Pushed, true);
            app.ClaculateGainButton_7.Text = 'Claculate Gain';
            app.RfohmsEditField_3Label = uilabel(app.DifferentiatorTab);
            app.RfohmsEditField_3Label.Text = 'Rf(ohms)';
            app.RfohmsEditField_3 = uieditfield(app.DifferentiatorTab, 'text');
            app.CffaradsEditFieldLabel = uilabel(app.DifferentiatorTab);
            app.CffaradsEditFieldLabel.Text = 'Cf(farads)';
            app.CffaradsEditField = uieditfield(app.DifferentiatorTab, 'text');
            app.VinvoltsEditFieldLabel = uilabel(app.DifferentiatorTab);
            app.VinvoltsEditFieldLabel.Text = 'Vin(volts)';
            app.VinvoltsEditField = uieditfield(app.DifferentiatorTab, 'text');
            app.GainAvEditField_11Label = uilabel(app.DifferentiatorTab);
            app.GainAvEditField_11Label.Text = 'Gain (Av)';
            app.GainAvEditField_11 = uieditfield(app.DifferentiatorTab, 'text');
            app.OutputVoltageEditField_5Label = uilabel(app.DifferentiatorTab);
            app.OutputVoltageEditField_5Label.Text = 'Output Voltage';
            app.OutputVoltageEditField_5 = uieditfield(app.DifferentiatorTab, 'text');
            app.IntegratorTab = uitab(app.TabGroup);
            app.IntegratorTab.Title = 'Integrator';
            app.IntegratorLabel = uilabel(app.IntegratorTab);
            app.IntegratorLabel.Text = 'Integrator';
            app.InputparametersLabel_6 = uilabel(app.IntegratorTab);
            app.InputparametersLabel_6.Text = 'Input parameters';
            app.SimulateIntegratorButton = uibutton(app.IntegratorTab, 'push');
            app.SimulateIntegratorButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateIntegratorButtonPushed, true);
            app.SimulateIntegratorButton.Text = 'Simulate Integrator';
            app.OutputparametersLabel_6 = uilabel(app.IntegratorTab);
            app.OutputparametersLabel_6.Text = 'Output parameters';
            app.Image2_5 = uiimage(app.IntegratorTab);
            app.Image2_5.ImageSource = fullfile(pathToMLAPP, 'integrator.png');
            app.DescriptionTextArea_7 = uitextarea(app.IntegratorTab);
            app.DescriptionTextArea_7.Editable = 'off';
            app.DescriptionTextArea_7.Value = {'6. Integrator  '; '- Configuration:  '; '  - The input is applied through a resistor R , and a capacitor C  is placed in the feedback path.  '; '- Output Voltage:  '; '  - The output is the time integral of the input signal:  '; '    V_{out} = -1/RC ∫ V_in.dt   '; '- Phase: '; '  - The output lags the input by 90 degrees.  '; '- Frequency Response:'; '  - Low-pass filter characteristics, attenuating high-frequency signals.  '; '- DC Gain:  '; '  - The output gain for DC signals is theoretically infinite, which can cause drift in practical applications. A small resistor is often added in parallel with the capacitor to limit this.  '; '- Application: '; '  - Used in analog computers, waveform shaping, generating triangular or ramp signals, and low-pass filtering in control systems.'; ''};
            app.RinohmsEditFieldLabel = uilabel(app.IntegratorTab);
            app.RinohmsEditFieldLabel.Text = 'Rin(ohms)';
            app.RinohmsEditField = uieditfield(app.IntegratorTab, 'text');
            app.CfaradsEditFieldLabel = uilabel(app.IntegratorTab);
            app.CfaradsEditFieldLabel.Text = 'C(farads)';
            app.CfaradsEditField = uieditfield(app.IntegratorTab, 'text');
            app.ClaculateGainButton_8 = uibutton(app.IntegratorTab, 'push');
            app.ClaculateGainButton_8.ButtonPushedFcn = createCallbackFcn(app, @ClaculateGainButton_8Pushed, true);
            app.ClaculateGainButton_8.Text = 'Claculate Gain';
            app.GainAvEditField_12Label = uilabel(app.IntegratorTab);
            app.GainAvEditField_12Label.Text = 'Gain (Av)';
            app.GainAvEditField_12 = uieditfield(app.IntegratorTab, 'text');
            app.OutputVoltageEditField_6Label = uilabel(app.IntegratorTab);
            app.OutputVoltageEditField_6Label.Text = 'Output Voltage';
            app.OutputVoltageEditField_6 = uieditfield(app.IntegratorTab, 'text');
            app.VinvoltsEditField_2Label = uilabel(app.IntegratorTab);
            app.VinvoltsEditField_2Label.Text = 'Vin(volts)';
            app.VinvoltsEditField_2 = uieditfield(app.IntegratorTab, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinalApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end